classdef EnergyDispersionRelation2DGraphite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        energyDispersion  matlab.ui.control.UIAxes
        aSliderLabel      matlab.ui.control.Label
        aSlider           matlab.ui.control.Slider
        sSliderLabel      matlab.ui.control.Label
        sSlider           matlab.ui.control.Slider
        teVSliderLabel    matlab.ui.control.Label
        tSlider           matlab.ui.control.Slider
        ResetButton       matlab.ui.control.Button
        Label             matlab.ui.control.Label
        Image             matlab.ui.control.Image
        Image2            matlab.ui.control.Image
        whilethefunctionkisgivenbyLabel  matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Image3            matlab.ui.control.Image
        e2pSliderLabel    matlab.ui.control.Label
        e2pSlider         matlab.ui.control.Slider
    end

    
    properties (Access = private)
        a;
        e2p;
        s;
        t;        
        k_x;
        k_y;
        omega;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.energyDispersion.cla;
            
            app.aSlider.Value = 1;
            app.e2pSlider.Value = 0;
            app.tSlider.Value = -3.033;
            app.sSlider.Value = 0.129;
            app.a = app.aSlider.Value;
            app.e2p = app.e2pSlider.Value;
            app.t = app.tSlider.Value;
            app.s = app.sSlider.Value;
            
            app.k_x = -2*pi/(sqrt(3)*app.a) : 0.1 : 2*pi/(sqrt(3)*app.a);
            %app.k_y = -3/2*pi/app.a : 0.1 : 3/2*pi/app.a;
            app.k_y = -2*3/(sqrt(2)*app.a) : 0.1 : 2*3/(sqrt(2)*app.a);
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.omega (i,j) = sqrt(1+4*cos(sqrt(3)*app.k_x(j)*app.a/2)*cos(app.k_y(i)*app.a/2)+ 4*cos(app.k_y(i)*app.a/2)*cos(app.k_y(i)*app.a/2));
                    Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            title(app.energyDispersion, 'Energy Dispersion');
            surf(app.energyDispersion, app.k_x, app.k_y,  Eg2Dn);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, app.k_x, app.k_y, Eg2Dp);
            xlabel (app.energyDispersion, 'k_x');
            ylabel (app.energyDispersion, 'k_y');
            zlabel (app.energyDispersion, 'E');
            zlab = get(app.energyDispersion,'ZLabel');
            set(zlab, 'Rotation', 0,'VerticalAlignment','middle')
            colorbar(app.energyDispersion);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            app.energyDispersion.cla;
            
            app.a = event.Value;
            app.k_x = -2*pi/(sqrt(3)*app.a) : 0.1 : 2*pi/(sqrt(3)*app.a);
            app.k_y = -3/2*pi/app.a : 0.1 : 3/2*pi/app.a;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.omega (i,j) = sqrt(1+4*cos(sqrt(3)*app.k_x(j)*app.a/2)*cos(app.k_y(i)*app.a/2)+ 4*cos(app.k_y(i)*app.a/2)*cos(app.k_y(i)*app.a/2));
                    Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersion, app.k_x, app.k_y,  Eg2Dn);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, app.k_x, app.k_y, Eg2Dp);
        end

        % Value changing function: sSlider
        function sSliderValueChanging(app, event)
            app.energyDispersion.cla;
            
            app.s = event.Value;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersion, app.k_x, app.k_y,  Eg2Dn);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, app.k_x, app.k_y, Eg2Dp);
        end

        % Value changing function: tSlider
        function tSliderValueChanging(app, event)
            app.energyDispersion.cla;
            
            app.t = event.Value;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersion, app.k_x, app.k_y,  Eg2Dn);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, app.k_x, app.k_y, Eg2Dp);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            startupFcn(app);
        end

        % Value changing function: e2pSlider
        function e2pSliderValueChanging(app, event)
            app.energyDispersion.cla;
            
            app.e2p = event.Value;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersion, app.k_x, app.k_y,  Eg2Dn);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, app.k_x, app.k_y, Eg2Dp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.energyDispersion = uiaxes(app.UIFigure);
            title(app.energyDispersion, 'Energy dispersion relation')
            xlabel(app.energyDispersion, 'X')
            ylabel(app.energyDispersion, 'Y')
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 1];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 1;
            app.sSliderLabel = uilabel(app.UIFigure);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.UIFigure);
            app.sSlider.Limits = [0 0.3];
            app.sSlider.ValueChangingFcn = createCallbackFcn(app, @sSliderValueChanging, true);
            app.sSlider.Value = 0.129;
            app.teVSliderLabel = uilabel(app.UIFigure);
            app.teVSliderLabel.Text = 't [eV]';
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.Limits = [-5 5];
            app.tSlider.ValueChangingFcn = createCallbackFcn(app, @tSliderValueChanging, true);
            app.tSlider.Value = -3.033;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'The energy dispersion relations is given by:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'w(k).png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'E1.png';
            app.whilethefunctionkisgivenbyLabel = uilabel(app.UIFigure);
            app.whilethefunctionkisgivenbyLabel.Text = 'while the function ω(k) is given by:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'In the case of s = 0 the relation becomes:';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'E2.png';
            app.e2pSliderLabel = uilabel(app.UIFigure);
            app.e2pSliderLabel.Text = 'e2p';
            app.e2pSlider = uislider(app.UIFigure);
            app.e2pSlider.Limits = [0 1];
            app.e2pSlider.ValueChangingFcn = createCallbackFcn(app, @e2pSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnergyDispersionRelation2DGraphite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end